classdef URTrayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        q8Slider               matlab.ui.control.Slider
        q8SliderLabel          matlab.ui.control.Label
        EditField_q8           matlab.ui.control.NumericEditField
        EditField_z            matlab.ui.control.NumericEditField
        EditField_y            matlab.ui.control.NumericEditField
        EditField_x            matlab.ui.control.NumericEditField
        EditField_q7           matlab.ui.control.NumericEditField
        EditField_q6           matlab.ui.control.NumericEditField
        EditField_q5           matlab.ui.control.NumericEditField
        EditField_q4           matlab.ui.control.NumericEditField
        EditField_q3           matlab.ui.control.NumericEditField
        EditField_q2           matlab.ui.control.NumericEditField
        CartesianControlLabel  matlab.ui.control.Label
        zSlider_2              matlab.ui.control.Slider
        zSlider_2Label         matlab.ui.control.Label
        ySlider_2              matlab.ui.control.Slider
        ySlider_2Label         matlab.ui.control.Label
        xSlider_2              matlab.ui.control.Slider
        xSlider_2Label         matlab.ui.control.Label
        zSlider                matlab.ui.control.Slider
        zSlider_3Label         matlab.ui.control.Label
        ySlider                matlab.ui.control.Slider
        ySliderLabel           matlab.ui.control.Label
        xSlider                matlab.ui.control.Slider
        xSliderLabel           matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        q7Slider               matlab.ui.control.Slider
        q7SliderLabel          matlab.ui.control.Label
        q6Slider_2             matlab.ui.control.Slider
        q6Slider_2Label        matlab.ui.control.Label
        q5Slider_2             matlab.ui.control.Slider
        q5Slider_2Label        matlab.ui.control.Label
        q1Slider_2             matlab.ui.control.Slider
        q1Slider_2Label        matlab.ui.control.Label
        q4Slider_2             matlab.ui.control.Slider
        q3Slider_2             matlab.ui.control.Slider
        q2Slider_2             matlab.ui.control.Slider
        q6Slider               matlab.ui.control.Slider
        q6SliderLabel          matlab.ui.control.Label
        q5Slider               matlab.ui.control.Slider
        q5SliderLabel          matlab.ui.control.Label
        q4Slider               matlab.ui.control.Slider
        q4SliderLabel          matlab.ui.control.Label
        q3Slider               matlab.ui.control.Slider
        q3SliderLabel          matlab.ui.control.Label
        q2Slider               matlab.ui.control.Slider
        q2SliderLabel          matlab.ui.control.Label
        q1Slider               matlab.ui.control.Slider
        q1SliderLabel          matlab.ui.control.Label
        Switch                 matlab.ui.control.Switch
        ToggleRobotToJogLabel  matlab.ui.control.Label
        ESTOPButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot %UR3e
        robot1 %URTray
        q % Joint angles UR3e
        q1 % Joint angles URTray
    end
    
    methods (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, robot1, robot2)
            app.robot1 = robot1;
            app.robot = robot2;
            app.q = app.robot.getpos();
            app.q1 = app.robot1.getpos();
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
           global e_stop;
            e_stop = ~e_stop;  % Toggle the e-stop state
            if e_stop
             disp('E-stop activated');
             set(eStopButton, 'String', 'E-Stop Active'); % Update button text
            else
             disp('E-stop deactivated');
             set(eStopButton, 'String', 'Toggle E-Stop'); % Reset button text
             end 
            
          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if strcmp(value, 'UR3e')

                 app.q1Slider.Visible = 'off';
                 app.q2Slider.Visible = 'off';
                 app.q3Slider.Visible = 'off';
                 app.q4Slider.Visible = 'off';
                 app.q5Slider.Visible = 'off';
                 app.q6Slider.Visible = 'off';
                 app.q7Slider.Visible = 'off';
                 app.q7SliderLabel.Visible = 'off';
                 app.xSlider.Visible = 'off';
                 app.ySlider.Visible = 'off';
                 app.zSlider.Visible = 'off';
                 app.EditField_q7.Visible ='off';
                 app.q8Slider.Visible = 'off';
                 app.q8SliderLabel.Visible = 'off';
                 app.EditField_q8.Visible ='off';

                 app.q1Slider_2.Visible = 'on';
                 app.q2Slider_2.Visible = 'on';
                 app.q3Slider_2.Visible = 'on';
                 app.q4Slider_2.Visible = 'on';
                 app.q5Slider_2.Visible = 'on';
                 app.q6Slider_2.Visible = 'on';
                 app.xSlider_2.Visible = 'on';
                 app.ySlider_2.Visible = 'on';
                 app.zSlider_2.Visible = 'on';

                 app.EditField.Value = app.q1Slider_2.Value;
                 app.EditField_q2.Value = app.q2Slider_2.Value;
                 app.EditField_q3.Value = app.q3Slider_2.Value;
                 app.EditField_q4.Value = app.q4Slider_2.Value;
                 app.EditField_q5.Value = app.q5Slider_2.Value;
                 app.EditField_q6.Value = app.q6Slider_2.Value;
                 app.EditField_x.Value = app.xSlider_2.Value;
                 app.EditField_y.Value = app.ySlider_2.Value;
                 app.EditField_z.Value = app.zSlider_2.Value;


                 app.q1Slider_2.Limits = [app.robot.qlim(1,1), app.robot.qlim(1,2)];
                 app.q2Slider_2.Limits = [app.robot.qlim(2,1), app.robot.qlim(2,2)];
                 app.q3Slider_2.Limits = [app.robot.qlim(3,1), app.robot.qlim(3,2)];
                 app.q4Slider_2.Limits = [app.robot.qlim(4,1), app.robot.qlim(4,2)];
                 app.q5Slider_2.Limits = [app.robot.qlim(5,1), app.robot.qlim(5,2)];
                 app.q6Slider_2.Limits = [app.robot.qlim(6,1), app.robot.qlim(6,2)];


            else


                 app.q1Slider.Visible = 'on';
                 app.q2Slider.Visible = 'on';
                 app.q3Slider.Visible = 'on';
                 app.q4Slider.Visible = 'on';
                 app.q5Slider.Visible = 'on';
                 app.q6Slider.Visible = 'on';
                 app.q7Slider.Visible = 'on';
                 app.q7SliderLabel.Visible = 'on';
                 app.xSlider.Visible = 'on';
                 app.ySlider.Visible = 'on';
                 app.zSlider.Visible = 'on';
                 app.EditField_q7.Visible ='on';
                 app.q8Slider.Visible = 'on';
                 app.q8SliderLabel.Visible = 'on';
                 app.EditField_q8.Visible ='on';


                 app.EditField.Value = app.q1Slider.Value;
                 app.EditField_q2.Value = app.q2Slider.Value;
                 app.EditField_q3.Value = app.q3Slider.Value;
                 app.EditField_q4.Value = app.q4Slider.Value;
                 app.EditField_q5.Value = app.q5Slider.Value;
                 app.EditField_q6.Value = app.q6Slider.Value;
                 app.EditField_x.Value = app.xSlider.Value;
                 app.EditField_y.Value = app.ySlider.Value;
                 app.EditField_z.Value = app.zSlider.Value;

                 app.q1Slider_2.Visible = 'off';   
                 app.q2Slider_2.Visible = 'off';
                 app.q3Slider_2.Visible = 'off';
                 app.q4Slider_2.Visible = 'off';
                 app.q5Slider_2.Visible = 'off';
                 app.q6Slider_2.Visible = 'off';
                 app.xSlider_2.Visible = 'off';
                 app.ySlider_2.Visible = 'off';
                 app.zSlider_2.Visible = 'off';

                 app.q1Slider.Limits = [app.robot1.qlim(1,1), app.robot1.qlim(1,2)];
                 app.q2Slider.Limits = [app.robot1.qlim(2,1), app.robot1.qlim(2,2)];
                 app.q3Slider.Limits = [app.robot1.qlim(3,1), app.robot1.qlim(3,2)];
                 app.q4Slider.Limits = [app.robot1.qlim(4,1), app.robot1.qlim(4,2)];
                 app.q5Slider.Limits = [app.robot1.qlim(5,1), app.robot1.qlim(5,2)];
                 app.q6Slider.Limits = [app.robot1.qlim(6,1), app.robot1.qlim(6,2)];
                 app.q7Slider.Limits = [app.robot1.qlim(7,1), app.robot1.qlim(7,2)];
                 app.q8Slider.Limits = [app.robot1.qlim(8,1), app.robot1.qlim(8,2)];
        
            end

            
        end

        % Value changing function: q1Slider_2
        function q1Slider_2ValueChanging(app, event)
            app.EditField.Value = event.Value;

            
             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(1)
                % update the stored joint coordinates
                info.q(1) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);


        end

        % Value changing function: xSlider_2
        function xSlider_2ValueChanging(app, event)
             h = findobj('Tag', app.robot.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
               endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_x.Value = endEffector(1,4);
            app.robot.delay = 0.001;

            info = get(h(1), 'UserData');

            if ~isempty(1)
            app.q = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*event.Value;
            vy = Kv*app.ySlider_2.Value;
            vz = Kv*app.zSlider_2.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot.jacob0(app.q);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q = app.q + dq'*dt;
            info.q(:) = app.q;
            set(h(1), 'UserData', info);
             
            end
            
            animate(app.robot, info.q);
            
           
        end

        % Value changing function: ySlider_2
        function ySlider_2ValueChanging(app, event)
             h = findobj('Tag', app.robot.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
              endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_y.Value = endEffector(2,4);
            app.robot.delay = 0.001;

            info = get(h(1), 'UserData');

            if ~isempty(1)
            app.q = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*app.xSlider_2.Value;
            vy = Kv*event.Value;
            vz = Kv*app.zSlider_2.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot.jacob0(app.q);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q = app.q + dq'*dt;
            info.q(:) = app.q;
            set(h(1), 'UserData', info);
             
            end
            
            animate(app.robot, info.q);
            
        end

        % Value changing function: zSlider_2
        function zSlider_2ValueChanging(app, event)
            h = findobj('Tag', app.robot.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end

              endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_z.Value = endEffector(3,4);
            app.robot.delay = 0.001;

            info = get(h(1), 'UserData');

            if ~isempty(1)
            app.q = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*app.xSlider_2.Value;
            vy = Kv*app.ySlider_2.Value;
            vz = Kv*event.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot.jacob0(app.q);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q = app.q + dq'*dt;
            info.q(:) = app.q;
            set(h(1), 'UserData', info);
             
            end
            
            animate(app.robot, info.q);


        end

        % Value changing function: q2Slider_2
        function q2Slider_2ValueChanging(app, event)
            app.EditField_q2.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(2)
                % update the stored joint coordinates
                info.q(2) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);
            
            
        end

        % Value changing function: q3Slider_2
        function q3Slider_2ValueChanging(app, event)
             app.EditField_q3.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(3)
                % update the stored joint coordinates
                info.q(3) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);
            
            
        end

        % Value changing function: q4Slider_2
        function q4Slider_2ValueChanging(app, event)
             app.EditField_q4.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(4)
                % update the stored joint coordinates
                info.q(4) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);
            
            
        end

        % Value changing function: q5Slider_2
        function q5Slider_2ValueChanging(app, event)
             app.EditField_q5.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(5)
                % update the stored joint coordinates
                info.q(5) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);
            
            
        end

        % Value changing function: q6Slider_2
        function q6Slider_2ValueChanging(app, event)
             app.EditField_q6.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(6)
                % update the stored joint coordinates
                info.q(6) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);
            
            
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
               app.EditField.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(1)
                % update the stored joint coordinates
                info.q(1) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);


        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            app.EditField_q2.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(2)
                % update the stored joint coordinates
                info.q(2) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
               app.EditField_q3.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(3)
                % update the stored joint coordinates
                info.q(3) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
               app.EditField_q4.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(4)
                % update the stored joint coordinates
                info.q(4) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
               app.EditField_q5.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(5)
                % update the stored joint coordinates
                info.q(5) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
               app.EditField_q6.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(6)
                % update the stored joint coordinates
                info.q(6) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q7Slider
        function q7SliderValueChanging(app, event)
               app.EditField_q7.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(7)
                % update the stored joint coordinates
                info.q(7) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: q8Slider
        function q8SliderValueChanging(app, event)
             app.EditField_q8.Value = event.Value;


             h = findobj('Tag', app.robot1.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(8)
                % update the stored joint coordinates
                info.q(8) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            h = findobj('Tag', app.robot1.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_x.Value = endEffector(1,4);
            %app.robot1.delay = 0.001;

            info = get(h(1), 'UserData');

          %  if ~isempty(1)
            app.q1 = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*event.Value;
            vy = Kv*app.ySlider.Value;
            vz = Kv*app.zSlider.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot1.jacob0(app.q1);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q1 = app.q1 + dq'*dt;
            info.q(:) = app.q1;
            set(h(1), 'UserData', info);

           % end

            animate(app.robot1, info.q);


        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            h = findobj('Tag', app.robot1.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
             endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_y.Value = endEffector(2,4);
            %app.robot1.delay = 0.001;

            info = get(h(1), 'UserData');

          %  if ~isempty(1)
            app.q1 = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*app.xSlider.Value;
            vy = Kv*event.Value;
            vz = Kv*app.zSlider.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot1.jacob0(app.q1);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q1 = app.q1 + dq'*dt;
            info.q(:) = app.q1;
            set(h(1), 'UserData', info);

           % end

            animate(app.robot1, info.q);
            
            
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            h = findobj('Tag', app.robot1.name);
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
             endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_z.Value = endEffector(3,4);
            %app.robot1.delay = 0.001;

            info = get(h(1), 'UserData');

          %  if ~isempty(1)
            app.q1 = info.q(:)';
            dt = 0.15;
            Kv = 0.2; % linear velocity gain
            Kw = 1.0; % angular velocity gain
            vx = Kv*app.xSlider.Value;
            vy = Kv*app.ySlider.Value;
            vz = Kv*event.Value;
            wx = 0;
            wy = 0;
            wz = 0;
            dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
            % 2 - use J inverse to calculate joint velocity
            J = app.robot1.jacob0(app.q1);
            dq = pinv(J)*dx;
            % 3 - apply joint velocity to step robot joint angles
            app.q1 = app.q1 + dq'*dt;
            info.q(:) = app.q1;
            set(h(1), 'UserData', info);

           % end

            animate(app.robot1, info.q);


        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_x.Value = endEffector(1,4);
            app.xSlider.Value = 0;
            
            
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
             app.ySlider.Value = 0;
           endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_y.Value = endEffector(2,4);
            
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
             app.zSlider.Value = 0;
           endEffector = app.robot1.fkine(app.robot1.getpos()).T;
            app.EditField_z.Value = endEffector(3,4);
        end

        % Value changed function: zSlider_2
        function zSlider_2ValueChanged(app, event)
           app.zSlider_2.Value = 0;
           endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_z.Value = endEffector(3,4);
            
        end

        % Value changed function: xSlider_2
        function xSlider_2ValueChanged(app, event)
           app.xSlider_2.Value = 0;
             endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_x.Value = endEffector(1,4);
            
        end

        % Value changed function: ySlider_2
        function ySlider_2ValueChanged(app, event)
           app.ySlider_2.Value = 0;
             endEffector = app.robot.fkine(app.robot.getpos()).T;
            app.EditField_y.Value = endEffector(2,4);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.ToggleRobotToJogLabel = uilabel(app.UIFigure);
            app.ToggleRobotToJogLabel.Text = 'Toggle Robot To Jog';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'URTray', 'UR3e'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'URTray';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.MajorTicks = [];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q1Slider.MinorTicks = [];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.MajorTicks = [];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.MinorTicks = [];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.MajorTicks = [];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.MinorTicks = [];
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.MajorTicks = [];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q4Slider.MinorTicks = [];
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.MajorTicks = [];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.MinorTicks = [];
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.MajorTicks = [];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.q6Slider.MinorTicks = [];
            app.q2Slider_2 = uislider(app.UIFigure);
            app.q2Slider_2.MajorTicks = [];
            app.q2Slider_2.ValueChangingFcn = createCallbackFcn(app, @q2Slider_2ValueChanging, true);
            app.q2Slider_2.MinorTicks = [];
            app.q3Slider_2 = uislider(app.UIFigure);
            app.q3Slider_2.MajorTicks = [];
            app.q3Slider_2.ValueChangingFcn = createCallbackFcn(app, @q3Slider_2ValueChanging, true);
            app.q3Slider_2.MinorTicks = [];
            app.q4Slider_2 = uislider(app.UIFigure);
            app.q4Slider_2.MajorTicks = [];
            app.q4Slider_2.ValueChangingFcn = createCallbackFcn(app, @q4Slider_2ValueChanging, true);
            app.q4Slider_2.MinorTicks = [];
            app.q1Slider_2Label = uilabel(app.UIFigure);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UIFigure);
            app.q1Slider_2.MajorTicks = [];
            app.q1Slider_2.ValueChangingFcn = createCallbackFcn(app, @q1Slider_2ValueChanging, true);
            app.q1Slider_2.MinorTicks = [];
            app.q5Slider_2Label = uilabel(app.UIFigure);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UIFigure);
            app.q5Slider_2.MajorTicks = [];
            app.q5Slider_2.ValueChangingFcn = createCallbackFcn(app, @q5Slider_2ValueChanging, true);
            app.q5Slider_2.MinorTicks = [];
            app.q6Slider_2Label = uilabel(app.UIFigure);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UIFigure);
            app.q6Slider_2.MajorTicks = [];
            app.q6Slider_2.ValueChangingFcn = createCallbackFcn(app, @q6Slider_2ValueChanging, true);
            app.q6Slider_2.MinorTicks = [];
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.MajorTicks = [];
            app.q7Slider.ValueChangingFcn = createCallbackFcn(app, @q7SliderValueChanging, true);
            app.q7Slider.MinorTicks = [];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-1 1];
            app.xSlider.MajorTicks = [];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.MinorTicks = [];
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-1 1];
            app.ySlider.MajorTicks = [];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.MinorTicks = [];
            app.zSlider_3Label = uilabel(app.UIFigure);
            app.zSlider_3Label.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-1 1];
            app.zSlider.MajorTicks = [];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.zSlider.MinorTicks = [];
            app.xSlider_2Label = uilabel(app.UIFigure);
            app.xSlider_2Label.Text = 'x';
            app.xSlider_2 = uislider(app.UIFigure);
            app.xSlider_2.Limits = [-1 1];
            app.xSlider_2.MajorTicks = [];
            app.xSlider_2.ValueChangedFcn = createCallbackFcn(app, @xSlider_2ValueChanged, true);
            app.xSlider_2.ValueChangingFcn = createCallbackFcn(app, @xSlider_2ValueChanging, true);
            app.xSlider_2.MinorTicks = [];
            app.ySlider_2Label = uilabel(app.UIFigure);
            app.ySlider_2Label.Text = 'y';
            app.ySlider_2 = uislider(app.UIFigure);
            app.ySlider_2.Limits = [-1 1];
            app.ySlider_2.MajorTicks = [];
            app.ySlider_2.ValueChangedFcn = createCallbackFcn(app, @ySlider_2ValueChanged, true);
            app.ySlider_2.ValueChangingFcn = createCallbackFcn(app, @ySlider_2ValueChanging, true);
            app.ySlider_2.MinorTicks = [];
            app.zSlider_2Label = uilabel(app.UIFigure);
            app.zSlider_2Label.Text = 'z';
            app.zSlider_2 = uislider(app.UIFigure);
            app.zSlider_2.Limits = [-1 1];
            app.zSlider_2.MajorTicks = [];
            app.zSlider_2.ValueChangedFcn = createCallbackFcn(app, @zSlider_2ValueChanged, true);
            app.zSlider_2.ValueChangingFcn = createCallbackFcn(app, @zSlider_2ValueChanging, true);
            app.zSlider_2.MinorTicks = [];
            app.CartesianControlLabel = uilabel(app.UIFigure);
            app.CartesianControlLabel.Text = 'Cartesian Control';
            app.EditField_q2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_x = uieditfield(app.UIFigure, 'numeric');
            app.EditField_y = uieditfield(app.UIFigure, 'numeric');
            app.EditField_z = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q8 = uieditfield(app.UIFigure, 'numeric');
            app.q8SliderLabel = uilabel(app.UIFigure);
            app.q8SliderLabel.Text = 'q8';
            app.q8Slider = uislider(app.UIFigure);
            app.q8Slider.MajorTicks = [];
            app.q8Slider.ValueChangingFcn = createCallbackFcn(app, @q8SliderValueChanging, true);
            app.q8Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = URTrayGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end